classdef testApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FlushButton                    matlab.ui.control.Button
        EditField                      matlab.ui.control.NumericEditField
        Label_2                        matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        CalcButton                     matlab.ui.control.Button
        NumberstoentryEditField        matlab.ui.control.NumericEditField
        NumberstoentryEditFieldLabel   matlab.ui.control.Label
        NumberofentriesEditField       matlab.ui.control.NumericEditField
        NumberofentriesEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcButton
        function CalcButtonPushed(app, event)
            global array_of_nums;
            
            test = test_class(array_of_nums);
            op_value = app.DropDown.Value;
            switch op_value(1)
                case 'A'
                    app.EditField.Value = test.add;
                case 'S'
                    if length(test.arrNum) ~= 0 
                        app.EditField.Value = test.subst;
                    else
                        app.EditField.Value = 0;
                    end    
                case 'M'
                    app.EditField.Value = test.x;
                otherwise
                    %%%
            end
                            
        end

        % Button pushed function: FlushButton
        function FlushButtonPushed(app, event)
            global array_of_nums;
            if ~isempty(app.NumberofentriesEditField.Value)
               num_of_entries = app.NumberofentriesEditField.get;
               num_of_entries = str2double(num_of_entries);
               i = length(array_of_nums);
            end    

            if ~isempty(app.NumberstoentryEditField.Value)
                if ~(i == (num_of_entries+1) ) 
                    i = length(array_of_nums);
                    array_of_nums(i+1) = app.NumberstoentryEditField.Value;
                    
                    app.NumberstoentryEditField.Value = [];
                end    
            else
               array_of_nums = [];
               i = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NumberofentriesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofentriesEditFieldLabel.Text = 'Number of entries';
            app.NumberofentriesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofentriesEditField.AllowEmpty = 'on';
            app.NumberofentriesEditField.Value = [];
            app.NumberstoentryEditFieldLabel = uilabel(app.UIFigure);
            app.NumberstoentryEditFieldLabel.Text = 'Numbers to entry';
            app.NumberstoentryEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberstoentryEditField.AllowEmpty = 'on';
            app.NumberstoentryEditField.Value = [];
            app.CalcButton = uibutton(app.UIFigure, 'push');
            app.CalcButton.ButtonPushedFcn = createCallbackFcn(app, @CalcButtonPushed, true);
            app.CalcButton.Text = 'Calc';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Addition', 'Substration', 'Multiplication'};
            app.DropDown.Value = 'Addition';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.AllowEmpty = 'on';
            app.EditField.Value = [];
            app.FlushButton = uibutton(app.UIFigure, 'push');
            app.FlushButton.ButtonPushedFcn = createCallbackFcn(app, @FlushButtonPushed, true);
            app.FlushButton.Text = 'Flush';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end